classdef FrameGen_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FrameGenUIFigure            matlab.ui.Figure
        InputPanel                  matlab.ui.container.Panel
        CalCurveButtonGroup         matlab.ui.container.ButtonGroup
        bitsplevelButton            matlab.ui.control.RadioButton
        bitplevelButton_2           matlab.ui.control.RadioButton
        NoneButton                  matlab.ui.control.RadioButton
        MiscDataPanel               matlab.ui.container.Panel
        VEditField_3                matlab.ui.control.EditField
        VEditField_3Label           matlab.ui.control.Label
        BEditField_3                matlab.ui.control.EditField
        BEditField_3Label           matlab.ui.control.Label
        GEditField_3                matlab.ui.control.EditField
        GEditField_3Label           matlab.ui.control.Label
        REditField_3                matlab.ui.control.EditField
        REditField_3Label           matlab.ui.control.Label
        RandomMiscCheckBox          matlab.ui.control.CheckBox
        RNDButton_4                 matlab.ui.control.Button
        RNDButton_3                 matlab.ui.control.Button
        RNDButton_2                 matlab.ui.control.Button
        RNDButton                   matlab.ui.control.Button
        FrameLengthEditField        matlab.ui.control.NumericEditField
        FrameLengthEditFieldLabel   matlab.ui.control.Label
        CellIDsPanel                matlab.ui.container.Panel
        xyzLabel                    matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        VEditField_2                matlab.ui.control.NumericEditField
        VEditField_2Label           matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        EditField_8                 matlab.ui.control.NumericEditField
        EditField_7                 matlab.ui.control.NumericEditField
        BEditField_2                matlab.ui.control.NumericEditField
        BEditField_2Label           matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        EditField_6                 matlab.ui.control.NumericEditField
        EditField_5                 matlab.ui.control.NumericEditField
        GEditField_2                matlab.ui.control.NumericEditField
        GEditField_2Label           matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        EditField_4                 matlab.ui.control.NumericEditField
        EditField_3                 matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        EditField_2                 matlab.ui.control.NumericEditField
        REditField_2                matlab.ui.control.NumericEditField
        REditField_2Label           matlab.ui.control.Label
        Pin2EditField               matlab.ui.control.NumericEditField
        Pin2EditFieldLabel          matlab.ui.control.Label
        Pin1EditField               matlab.ui.control.NumericEditField
        Pin1EditFieldLabel          matlab.ui.control.Label
        StopbitEditField            matlab.ui.control.NumericEditField
        StopbitEditFieldLabel       matlab.ui.control.Label
        SteeringAngleDropDown       matlab.ui.control.DropDown
        SteeringAngleDropDownLabel  matlab.ui.control.Label
        SynchCheckBox               matlab.ui.control.CheckBox
        OutputPanel                 matlab.ui.container.Panel
        PiscaLedCodeLabel           matlab.ui.control.Label
        CopyButton_2                matlab.ui.control.Button
        CopyButton                  matlab.ui.control.Button
        CopyVButton                 matlab.ui.control.Button
        CopyBButton                 matlab.ui.control.Button
        CopyGButton                 matlab.ui.control.Button
        ParityEditField             matlab.ui.control.EditField
        ParityEditFieldLabel        matlab.ui.control.Label
        DataEditField               matlab.ui.control.EditField
        DataEditFieldLabel          matlab.ui.control.Label
        CopyRButton                 matlab.ui.control.Button
        VEditField                  matlab.ui.control.EditField
        VEditFieldLabel             matlab.ui.control.Label
        BEditField                  matlab.ui.control.EditField
        BEditFieldLabel             matlab.ui.control.Label
        GEditField                  matlab.ui.control.EditField
        GEditFieldLabel             matlab.ui.control.Label
        REditField                  matlab.ui.control.EditField
        REditFieldLabel             matlab.ui.control.Label
        STARTButton                 matlab.ui.control.Button
    end

    
    methods (Access = private)

        function bits = number24bits(app,number)
            if number >= 0
                switch number
                    case 0
                        bits = [0 0 0 0];
                    case 1
                        bits = [0 0 0 1];
                    case 2
                        bits = [0 0 1 0];
                    case 3
                        bits = [0 0 1 1];
                    case 4
                        bits = [0 1 0 0];
                    case 5
                        bits = [0 1 0 1];
                    case 6
                        bits = [0 1 1 0];
                    case 7
                        bits = [0 1 1 1];
                    case 8
                        bits = [1 0 0 0];
                    case 9
                        bits = [1 0 0 1];
                end
            else
                switch number
                    case -1
                        bits = [1 0 0 1];
                    case -2
                        bits = [1 0 1 0];
                    case -3
                        bits = [1 0 1 1];
                    case -4
                        bits = [1 1 0 0];
                    case -5
                        bits = [1 1 0 1];
                    case -6
                        bits = [1 1 1 0];
                    case -7
                        bits = [1 1 1 1];
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            enable.synch = app.SynchCheckBox.Value;
            %enable.cc = app.CalCurveCheckBox.Value;
            L = app.FrameLengthEditField.Value;
            
            %SYNCH
            if enable.synch
                OUT_SYNCH.gen = [1 0 1 0 1];
            else
                OUT_SYNCH.gen = [];
            end
            OUT_SYNCH.R = OUT_SYNCH.gen;
            OUT_SYNCH.G = OUT_SYNCH.gen;
            OUT_SYNCH.B = OUT_SYNCH.gen;
            OUT_SYNCH.V = OUT_SYNCH.gen;

            %CALIBRATION CURVE
            if app.NoneButton.Value
                % Nothing happens
                OUT_CC.R = [];
                OUT_CC.G = [];
                OUT_CC.B = [];
                OUT_CC.V = [];
            else if app.bitplevelButton_2.Value
                    % 1 bit per level
                    OUT_CC.R = [0 0 0 0 0 0 0 0 1 1 1 1 1 1 1 1];
                    OUT_CC.G = [0 0 0 0 1 1 1 1 0 0 0 0 1 1 1 1];
                    OUT_CC.B = [0 0 1 1 0 0 1 1 0 0 1 1 0 0 1 1];
                    OUT_CC.V = [0 1 0 1 0 1 0 1 0 1 0 1 0 1 0 1];
                    else if app.bitsplevelButton.Value
                                % 2 bits per level
                                OUT_CC.R = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1];
                                OUT_CC.G = [0 0 0 0 0 0 0 0 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 1 1 1 1 1 1 1 1];
                                OUT_CC.B = [0 0 0 0 1 1 1 1 0 0 0 0 1 1 1 1 0 0 0 0 1 1 1 1 0 0 0 0 1 1 1 1];
                                OUT_CC.V = [0 0 1 1 0 0 1 1 0 0 1 1 0 0 1 1 0 0 1 1 0 0 1 1 0 0 1 1 0 0 1 1];
                    end
                end
            end

            %CELLS
            R(1)=app.REditField_2.Value;
            R(2)=app.EditField_2.Value;
            R(3)=app.EditField.Value;
            G(1)=app.GEditField_2.Value;
            G(2)=app.EditField_3.Value;
            G(3)=app.EditField_4.Value;
            B(1)=app.BEditField_2.Value;
            B(2)=app.EditField_5.Value;
            B(3)=app.EditField_6.Value;
            V(1)=app.VEditField_2.Value;
            V(2)=app.EditField_7.Value;
            V(3)=app.EditField_8.Value;
            
            temp_r1 = number24bits(app,R(1));
            temp_r2 = number24bits(app,R(2));
            temp_r3 = number24bits(app,R(3));
            temp_g1 = number24bits(app,G(1));
            temp_g2 = number24bits(app,G(2));
            temp_g3 = number24bits(app,G(3));
            temp_b1 = number24bits(app,B(1));
            temp_b2 = number24bits(app,B(2));
            temp_b3 = number24bits(app,B(3));
            temp_v1 = number24bits(app,V(1));
            temp_v2 = number24bits(app,V(2));
            temp_v3 = number24bits(app,V(3));
            OUT_X.R = temp_r1;
            OUT_X.G = temp_g1;
            OUT_X.B = temp_b1;
            OUT_X.V = temp_v1;
            OUT_Y.R = temp_r2;
            OUT_Y.G = temp_g2;
            OUT_Y.B = temp_b2;
            OUT_Y.V = temp_v2;
            OUT_Z.R = temp_r3;
            OUT_Z.G = temp_g3;
            OUT_Z.B = temp_b3;
            OUT_Z.V = temp_v3;

            %PIN1
            Pin1 = app.Pin1EditField.Value;
            m = floor(log10(Pin1));
            temp_pin1 = mod(floor(Pin1 ./ 10 .^ (m:-1:0)), 10);
            OUT_PIN1.R = number24bits(app,temp_pin1(1));
            OUT_PIN1.G = number24bits(app,temp_pin1(2));
            OUT_PIN1.B = number24bits(app,temp_pin1(3));
            OUT_PIN1.V = number24bits(app,temp_pin1(4));

            %PIN2
            Pin2 = app.Pin2EditField.Value;
            m = floor(log10(Pin2));
            temp_pin2 = mod(floor(Pin2 ./ 10 .^ (m:-1:0)), 10);
            OUT_PIN2.R = number24bits(app,temp_pin2(1));
            OUT_PIN2.G = number24bits(app,temp_pin2(2));
            OUT_PIN2.B = number24bits(app,temp_pin2(3));
            OUT_PIN2.V = number24bits(app,temp_pin2(4));

            %ANGLE
            Steering_Angle = app.SteeringAngleDropDown.Value;
            if strcmp(Steering_Angle,'N') %5
                temp = [0 1 0 1];
            else
                if strcmp(Steering_Angle,'NE')%4
                    temp = [0 1 0 0];
                else
                    if strcmp(Steering_Angle,'E')%3
                        temp = [0 0 1 1];
                    else
                        if strcmp(Steering_Angle,'SE')%2
                            temp = [0 0 1 0];
                        else
                            if strcmp(Steering_Angle,'S')%9
                                temp = [1 0 0 1];
                            else
                                if strcmp(Steering_Angle,'SW')%8
                                    temp = [1 0 0 0];
                                else
                                    if strcmp(Steering_Angle,'W')%7
                                        temp = [0 1 1 1];
                                    else
                                        if strcmp(Steering_Angle,'NW')%6
                                            temp = [0 1 1 0];
                                        else
                                            error('Invalid Steering Angle!');
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
            end
            OUT_ANGLE.R = temp;
            OUT_ANGLE.G = temp;
            OUT_ANGLE.B = temp;
            OUT_ANGLE.V = temp;

            %MISC. DATA
            %Misc_Data = app.MiscEditField.Value;
            random.misc = app.RandomMiscCheckBox.Value;
            OUT_R = [OUT_SYNCH.R OUT_CC.R OUT_X.R OUT_Y.R OUT_Z.R OUT_PIN1.R OUT_PIN2.R OUT_ANGLE.R];
            OUT_G = [OUT_SYNCH.G OUT_CC.G OUT_X.G OUT_Y.G OUT_Z.G OUT_PIN1.G OUT_PIN2.G OUT_ANGLE.G];
            OUT_B = [OUT_SYNCH.B OUT_CC.B OUT_X.B OUT_Y.B OUT_Z.B OUT_PIN1.B OUT_PIN2.B OUT_ANGLE.B];
            OUT_V = [OUT_SYNCH.V OUT_CC.V OUT_X.V OUT_Y.V OUT_Z.V OUT_PIN1.V OUT_PIN2.V OUT_ANGLE.V];
            current_length = length(OUT_R);
            if random.misc
                OUT_MISC.R = randi([0 1],1,(L-current_length)-1);
                OUT_MISC.G = randi([0 1],1,(L-current_length)-1);
                OUT_MISC.B = randi([0 1],1,(L-current_length)-1);
                OUT_MISC.V = randi([0 1],1,(L-current_length)-1);
            else
                R_MISC = app.REditField_3.Value;
                G_MISC = app.GEditField_3.Value;
                B_MISC = app.BEditField_3.Value;
                V_MISC = app.VEditField_3.Value;
                R_MISC = erase(R_MISC,{',',' ','-'});
                G_MISC = erase(G_MISC,{',',' ','-'});
                B_MISC = erase(B_MISC,{',',' ','-'});
                V_MISC = erase(V_MISC,{',',' ','-'});
                
                R_TEMP = zeros(1,length(R_MISC));
                G_TEMP = zeros(1,length(G_MISC));
                B_TEMP = zeros(1,length(B_MISC));
                V_TEMP = zeros(1,length(V_MISC));
                for i=1:length(R_TEMP)
                    R_TEMP(i) = str2double(R_MISC(i));
                    G_TEMP(i) = str2double(G_MISC(i));
                    B_TEMP(i) = str2double(B_MISC(i));
                    V_TEMP(i) = str2double(V_MISC(i));
                end
                
                OUT_MISC.R = zeros(1,(L-current_length)-1);
                OUT_MISC.G = OUT_MISC.R;
                OUT_MISC.B = OUT_MISC.R;
                OUT_MISC.V = OUT_MISC.R;
                for i = 1:1:((L-current_length)-1)*1
                    OUT_MISC.R(i) = R_TEMP(i);
                    OUT_MISC.G(i) = G_TEMP(i);
                    OUT_MISC.B(i) = B_TEMP(i);
                    OUT_MISC.V(i) = V_TEMP(i);                    
                end
                %OUT_MISC.R = ones(1,(L-current_length)-1)*Misc_Data;
                %OUT_MISC.G = ones(1,(L-current_length)-1)*Misc_Data;
                %OUT_MISC.B = ones(1,(L-current_length)-1)*Misc_Data;
                %OUT_MISC.V = ones(1,(L-current_length)-1)*Misc_Data;
            end

            %STOP BIT
            OUT_STOP.R = 0;
            OUT_STOP.G = 0;
            OUT_STOP.B = 0;
            OUT_STOP.V = 0;

            %OUTPUT
            OUT_R = [OUT_R OUT_MISC.R OUT_STOP.R];
            OUT_G = [OUT_G OUT_MISC.G OUT_STOP.G];
            OUT_B = [OUT_B OUT_MISC.B OUT_STOP.B];
            OUT_V = [OUT_V OUT_MISC.V OUT_STOP.V];

            PLC_R = OUT_R;
            PLC_G = OUT_G;
            PLC_B = OUT_B;
            PLC_V = OUT_V;
            
            OUT_R = num2str(OUT_R);
            OUT_G = num2str(OUT_G);
            OUT_B = num2str(OUT_B);
            OUT_V = num2str(OUT_V);
            OUT_R_FINAL = [];
            OUT_G_FINAL = [];
            OUT_B_FINAL = [];
            OUT_V_FINAL = [];
            temp=1:3:L*3;
            for i=1:3:L*3
                OUT_R_FINAL = [OUT_R_FINAL OUT_R(i)];
                OUT_G_FINAL = [OUT_G_FINAL OUT_G(i)];
                OUT_B_FINAL = [OUT_B_FINAL OUT_B(i)];
                OUT_V_FINAL = [OUT_V_FINAL OUT_V(i)];
                if i~=temp(end)
                    OUT_R_FINAL = [OUT_R_FINAL,','];
                    OUT_G_FINAL = [OUT_G_FINAL,','];
                    OUT_B_FINAL = [OUT_B_FINAL,','];
                    OUT_V_FINAL = [OUT_V_FINAL,','];
                end
            end
            app.REditField.Value = OUT_R_FINAL;
            app.GEditField.Value = OUT_G_FINAL;
            app.BEditField.Value = OUT_B_FINAL;
            app.VEditField.Value = OUT_V_FINAL;
            
            %PISCALED CODE
            PiscaLed_Code = [];
            for i = 1:length(PLC_R)
                if PLC_R(i) == 0 && PLC_G(i) == 0 && PLC_B(i) == 0 && PLC_V(i) == 0 %0
                    PiscaLed_Code = [PiscaLed_Code '0000'];
                end
                if PLC_R(i) == 0 && PLC_G(i) == 0 && PLC_B(i) == 0 && PLC_V(i) == 1 %1
                    PiscaLed_Code = [PiscaLed_Code 'F080'];
                end
                if PLC_R(i) == 0 && PLC_G(i) == 0 && PLC_B(i) == 1 && PLC_V(i) == 0 %2
                    PiscaLed_Code = [PiscaLed_Code 'FF00'];
                end
                if PLC_R(i) == 0 && PLC_G(i) == 0 && PLC_B(i) == 1 && PLC_V(i) == 1 %3
                    PiscaLed_Code = [PiscaLed_Code 'FF80'];
                end
                if PLC_R(i) == 0 && PLC_G(i) == 1 && PLC_B(i) == 0 && PLC_V(i) == 0 %4
                    PiscaLed_Code = [PiscaLed_Code 'F020'];
                end
                if PLC_R(i) == 0 && PLC_G(i) == 1 && PLC_B(i) == 0 && PLC_V(i) == 1 %5
                    PiscaLed_Code = [PiscaLed_Code 'F0F0'];
                end
                if PLC_R(i) == 0 && PLC_G(i) == 1 && PLC_B(i) == 1 && PLC_V(i) == 0 %6
                    PiscaLed_Code = [PiscaLed_Code 'FF20'];
                end
                if PLC_R(i) == 0 && PLC_G(i) == 1 && PLC_B(i) == 1 && PLC_V(i) == 1 %7
                    PiscaLed_Code = [PiscaLed_Code 'FFF0'];
                end
                if PLC_R(i) == 1 && PLC_G(i) == 0 && PLC_B(i) == 0 && PLC_V(i) == 0 %8
                    PiscaLed_Code = [PiscaLed_Code 'F00F'];
                end
                if PLC_R(i) == 1 && PLC_G(i) == 0 && PLC_B(i) == 0 && PLC_V(i) == 1 %9
                    PiscaLed_Code = [PiscaLed_Code 'F08F'];
                end
                if PLC_R(i) == 1 && PLC_G(i) == 0 && PLC_B(i) == 1 && PLC_V(i) == 0 %10
                    PiscaLed_Code = [PiscaLed_Code 'FF0F'];
                end
                if PLC_R(i) == 1 && PLC_G(i) == 0 && PLC_B(i) == 1 && PLC_V(i) == 1 %11
                    PiscaLed_Code = [PiscaLed_Code 'FF8F'];
                end
                if PLC_R(i) == 1 && PLC_G(i) == 1 && PLC_B(i) == 0 && PLC_V(i) == 0 %12
                    PiscaLed_Code = [PiscaLed_Code 'F02F'];
                end
                if PLC_R(i) == 1 && PLC_G(i) == 1 && PLC_B(i) == 0 && PLC_V(i) == 1 %13
                    PiscaLed_Code = [PiscaLed_Code 'F0FF'];
                end
                if PLC_R(i) == 1 && PLC_G(i) == 1 && PLC_B(i) == 1 && PLC_V(i) == 0 %14
                    PiscaLed_Code = [PiscaLed_Code 'FF2F'];
                end
                if PLC_R(i) == 1 && PLC_G(i) == 1 && PLC_B(i) == 1 && PLC_V(i) == 1 %15
                    PiscaLed_Code = [PiscaLed_Code 'FFFF'];
                end
                PiscaLed_Code = [PiscaLed_Code ' '];
            end
            app.DataEditField.Value = PiscaLed_Code;
            
            %PARITY SIGNAL
            Rp = zeros(1,length(PLC_R));
            Gp = zeros(1,length(PLC_G));
            Bp = zeros(1,length(PLC_B));
            Vp = zeros(1,length(PLC_V));
            for z = 1:length(Rp)
                Rp(z) = xor(xor(PLC_V(z),PLC_R(z)),PLC_B(z));
                Gp(z) = xor(xor(PLC_V(z),PLC_R(z)),PLC_G(z));
                Bp(z) = xor(xor(PLC_V(z),PLC_G(z)),PLC_B(z));
            end

            %PARITY PISCALED CODE
            Parity_PiscaLed_Code = [];
            for i = 1:length(Rp)
                if Rp(i) == 0 && Gp(i) == 0 && Bp(i) == 0 && Vp(i) == 0 %0
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code '0000'];
                end
                if Rp(i) == 0 && Gp(i) == 0 && Bp(i) == 0 && Vp(i) == 1 %1
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'F080'];
                end
                if Rp(i) == 0 && Gp(i) == 0 && Bp(i) == 1 && Vp(i) == 0 %2
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'FF00'];
                end
                if Rp(i) == 0 && Gp(i) == 0 && Bp(i) == 1 && Vp(i) == 1 %3
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'FF80'];
                end
                if Rp(i) == 0 && Gp(i) == 1 && Bp(i) == 0 && Vp(i) == 0 %4
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'F020'];
                end
                if Rp(i) == 0 && Gp(i) == 1 && Bp(i) == 0 && Vp(i) == 1 %5
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'F0F0'];
                end
                if Rp(i) == 0 && Gp(i) == 1 && Bp(i) == 1 && Vp(i) == 0 %6
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'FF20'];
                end
                if Rp(i) == 0 && Gp(i) == 1 && Bp(i) == 1 && Vp(i) == 1 %7
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'FFF0'];
                end
                if Rp(i) == 1 && Gp(i) == 0 && Bp(i) == 0 && Vp(i) == 0 %8
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'F00F'];
                end
                if Rp(i) == 1 && Gp(i) == 0 && Bp(i) == 0 && Vp(i) == 1 %9
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'F08F'];
                end
                if Rp(i) == 1 && Gp(i) == 0 && Bp(i) == 1 && Vp(i) == 0 %10
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'FF0F'];
                end
                if Rp(i) == 1 && Gp(i) == 0 && Bp(i) == 1 && Vp(i) == 1 %11
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'FF8F'];
                end
                if Rp(i) == 1 && Gp(i) == 1 && Bp(i) == 0 && Vp(i) == 0 %12
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'F02F'];
                end
                if Rp(i) == 1 && Gp(i) == 1 && Bp(i) == 0 && Vp(i) == 1 %13
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'F0FF'];
                end
                if Rp(i) == 1 && Gp(i) == 1 && Bp(i) == 1 && Vp(i) == 0 %14
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'FF2F'];
                end
                if Rp(i) == 1 && Gp(i) == 1 && Bp(i) == 1 && Vp(i) == 1 %15
                    Parity_PiscaLed_Code = [Parity_PiscaLed_Code 'FFFF'];
                end
                Parity_PiscaLed_Code = [Parity_PiscaLed_Code ' '];
            end
            app.ParityEditField.Value = Parity_PiscaLed_Code;
        end

        % Button pushed function: RNDButton
        function RNDButtonPushed(app, event)
            Pin1 = randi([1 9],1,4);
            app.Pin1EditField.Value = Pin1(1)*1000+Pin1(2)*100+Pin1(3)*10+Pin1(4);
        end

        % Button pushed function: RNDButton_2
        function RNDButton_2Pushed(app, event)
            Pin2 = randi([1 9],1,4);
            app.Pin2EditField.Value = Pin2(1)*1000+Pin2(2)*100+Pin2(3)*10+Pin2(4);
        end

        % Value changed function: RandomMiscCheckBox
        function RandomMiscCheckBoxValueChanged(app, event)
            value = app.RandomMiscCheckBox.Value;
            %app.REditField_3
            if value
                app.REditField_3.Enable = 'off';
                app.GEditField_3.Enable = 'off';
                app.BEditField_3.Enable = 'off';
                app.VEditField_3.Enable = 'off';
            else
                app.REditField_3.Enable = 'on';
                app.GEditField_3.Enable = 'on';
                app.BEditField_3.Enable = 'on';
                app.VEditField_3.Enable = 'on';
            end
        end

        % Button pushed function: RNDButton_3
        function RNDButton_3Pushed(app, event)
            random_angle = randi([1 8],1,1);
            switch random_angle
                case 1
                    app.SteeringAngleDropDown.Value='N';
                case 2
                    app.SteeringAngleDropDown.Value='NW';
                case 3
                    app.SteeringAngleDropDown.Value='NE';
                case 4
                    app.SteeringAngleDropDown.Value='W';
                case 5
                    app.SteeringAngleDropDown.Value='E';
                case 6
                    app.SteeringAngleDropDown.Value='SW';
                case 7
                    app.SteeringAngleDropDown.Value='SE';
                case 8
                    app.SteeringAngleDropDown.Value='S';
            end
        end

        % Button pushed function: RNDButton_4
        function RNDButton_4Pushed(app, event)
            floor_number = randi([-7 7],1,1);
            app.REditField_2.Value = randi([0 9],1,1);
            app.EditField_2.Value = randi([0 9],1,1);
            app.EditField.Value = floor_number;%
            app.GEditField_2.Value = randi([0 9],1,1);
            app.EditField_3.Value = randi([0 9],1,1);
            app.EditField_4.Value = floor_number;%
            app.BEditField_2.Value = randi([0 9],1,1);
            app.EditField_5.Value = randi([0 9],1,1);
            app.EditField_6.Value = floor_number;%
            app.VEditField_2.Value = randi([0 9],1,1);
            app.EditField_7.Value = randi([0 9],1,1);
            app.EditField_8.Value = floor_number;%
        end

        % Button pushed function: CopyRButton
        function CopyRButtonPushed(app, event)
            clipboard('copy',app.REditField.Value);
        end

        % Button pushed function: CopyGButton
        function CopyGButtonPushed(app, event)
            clipboard('copy',app.GEditField.Value);
        end

        % Button pushed function: CopyBButton
        function CopyBButtonPushed(app, event)
            clipboard('copy',app.BEditField.Value);
        end

        % Button pushed function: CopyVButton
        function CopyVButtonPushed(app, event)
            clipboard('copy',app.VEditField.Value);
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            clipboard('copy',app.DataEditField.Value);
        end

        % Button pushed function: CopyButton_2
        function CopyButton_2Pushed(app, event)
            clipboard('copy',app.ParityEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FrameGenUIFigure = uifigure('Visible', 'off');
            app.FrameGenUIFigure.Name = 'FrameGen';
            app.STARTButton = uibutton(app.FrameGenUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.OutputPanel = uipanel(app.FrameGenUIFigure);
            app.OutputPanel.Title = 'Output';
            app.REditFieldLabel = uilabel(app.OutputPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.OutputPanel, 'text');
            app.GEditFieldLabel = uilabel(app.OutputPanel);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.OutputPanel, 'text');
            app.BEditFieldLabel = uilabel(app.OutputPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.OutputPanel, 'text');
            app.VEditFieldLabel = uilabel(app.OutputPanel);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.OutputPanel, 'text');
            app.CopyRButton = uibutton(app.OutputPanel, 'push');
            app.CopyRButton.ButtonPushedFcn = createCallbackFcn(app, @CopyRButtonPushed, true);
            app.CopyRButton.Text = 'Copy R';
            app.DataEditFieldLabel = uilabel(app.OutputPanel);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.OutputPanel, 'text');
            app.ParityEditFieldLabel = uilabel(app.OutputPanel);
            app.ParityEditFieldLabel.Text = 'Parity';
            app.ParityEditField = uieditfield(app.OutputPanel, 'text');
            app.CopyGButton = uibutton(app.OutputPanel, 'push');
            app.CopyGButton.ButtonPushedFcn = createCallbackFcn(app, @CopyGButtonPushed, true);
            app.CopyGButton.Text = 'Copy G';
            app.CopyBButton = uibutton(app.OutputPanel, 'push');
            app.CopyBButton.ButtonPushedFcn = createCallbackFcn(app, @CopyBButtonPushed, true);
            app.CopyBButton.Text = 'Copy B';
            app.CopyVButton = uibutton(app.OutputPanel, 'push');
            app.CopyVButton.ButtonPushedFcn = createCallbackFcn(app, @CopyVButtonPushed, true);
            app.CopyVButton.Text = 'Copy V';
            app.CopyButton = uibutton(app.OutputPanel, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.CopyButton_2 = uibutton(app.OutputPanel, 'push');
            app.CopyButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopyButton_2Pushed, true);
            app.CopyButton_2.Text = 'Copy';
            app.PiscaLedCodeLabel = uilabel(app.OutputPanel);
            app.PiscaLedCodeLabel.Text = {'PiscaLed'; 'Code:'};
            app.InputPanel = uipanel(app.FrameGenUIFigure);
            app.InputPanel.Title = 'Input';
            app.SynchCheckBox = uicheckbox(app.InputPanel);
            app.SynchCheckBox.Text = 'Synch';
            app.SynchCheckBox.Value = true;
            app.SteeringAngleDropDownLabel = uilabel(app.InputPanel);
            app.SteeringAngleDropDownLabel.Text = 'Steering Angle';
            app.SteeringAngleDropDown = uidropdown(app.InputPanel);
            app.SteeringAngleDropDown.Items = {'N', 'NW', 'NE', 'W', 'E', 'SW', 'SE', 'S'};
            app.SteeringAngleDropDown.Value = 'N';
            app.StopbitEditFieldLabel = uilabel(app.InputPanel);
            app.StopbitEditFieldLabel.Text = 'Stop bit';
            app.StopbitEditField = uieditfield(app.InputPanel, 'numeric');
            app.StopbitEditField.Limits = [0 1];
            app.StopbitEditField.Editable = 'off';
            app.Pin1EditFieldLabel = uilabel(app.InputPanel);
            app.Pin1EditFieldLabel.Text = 'Pin1';
            app.Pin1EditField = uieditfield(app.InputPanel, 'numeric');
            app.Pin1EditField.Limits = [0 9999];
            app.Pin1EditField.Value = 1234;
            app.Pin2EditFieldLabel = uilabel(app.InputPanel);
            app.Pin2EditFieldLabel.Text = 'Pin2';
            app.Pin2EditField = uieditfield(app.InputPanel, 'numeric');
            app.Pin2EditField.Limits = [0 9999];
            app.Pin2EditField.Value = 6789;
            app.CellIDsPanel = uipanel(app.InputPanel);
            app.CellIDsPanel.Title = 'Cell IDs';
            app.REditField_2Label = uilabel(app.CellIDsPanel);
            app.REditField_2Label.Text = 'R';
            app.REditField_2 = uieditfield(app.CellIDsPanel, 'numeric');
            app.REditField_2.Value = 3;
            app.EditField_2 = uieditfield(app.CellIDsPanel, 'numeric');
            app.EditField_2.Value = 4;
            app.EditField = uieditfield(app.CellIDsPanel, 'numeric');
            app.EditField.Limits = [-9 9];
            app.EditField.Value = 1;
            app.Label = uilabel(app.CellIDsPanel);
            app.Label.Text = ',';
            app.EditField_3 = uieditfield(app.CellIDsPanel, 'numeric');
            app.EditField_3.Value = 4;
            app.EditField_4 = uieditfield(app.CellIDsPanel, 'numeric');
            app.EditField_4.Value = 1;
            app.Label_2 = uilabel(app.CellIDsPanel);
            app.Label_2.Text = ',';
            app.GEditField_2Label = uilabel(app.CellIDsPanel);
            app.GEditField_2Label.Text = 'G';
            app.GEditField_2 = uieditfield(app.CellIDsPanel, 'numeric');
            app.GEditField_2.Value = 4;
            app.EditField_5 = uieditfield(app.CellIDsPanel, 'numeric');
            app.EditField_5.Value = 3;
            app.EditField_6 = uieditfield(app.CellIDsPanel, 'numeric');
            app.EditField_6.Value = 1;
            app.Label_3 = uilabel(app.CellIDsPanel);
            app.Label_3.Text = ',';
            app.BEditField_2Label = uilabel(app.CellIDsPanel);
            app.BEditField_2Label.Text = 'B';
            app.BEditField_2 = uieditfield(app.CellIDsPanel, 'numeric');
            app.BEditField_2.Value = 4;
            app.EditField_7 = uieditfield(app.CellIDsPanel, 'numeric');
            app.EditField_7.Value = 3;
            app.EditField_8 = uieditfield(app.CellIDsPanel, 'numeric');
            app.EditField_8.Value = 1;
            app.Label_4 = uilabel(app.CellIDsPanel);
            app.Label_4.Text = ',';
            app.VEditField_2Label = uilabel(app.CellIDsPanel);
            app.VEditField_2Label.Text = 'V';
            app.VEditField_2 = uieditfield(app.CellIDsPanel, 'numeric');
            app.VEditField_2.Value = 3;
            app.Label_5 = uilabel(app.CellIDsPanel);
            app.Label_5.Text = ',';
            app.Label_6 = uilabel(app.CellIDsPanel);
            app.Label_6.Text = ',';
            app.Label_7 = uilabel(app.CellIDsPanel);
            app.Label_7.Text = ',';
            app.Label_8 = uilabel(app.CellIDsPanel);
            app.Label_8.Text = ',';
            app.xyzLabel = uilabel(app.CellIDsPanel);
            app.xyzLabel.Text = '  x       y      z';
            app.FrameLengthEditFieldLabel = uilabel(app.InputPanel);
            app.FrameLengthEditFieldLabel.Text = 'Frame Length';
            app.FrameLengthEditField = uieditfield(app.InputPanel, 'numeric');
            app.FrameLengthEditField.Limits = [1 999];
            app.FrameLengthEditField.Value = 64;
            app.RNDButton = uibutton(app.InputPanel, 'push');
            app.RNDButton.ButtonPushedFcn = createCallbackFcn(app, @RNDButtonPushed, true);
            app.RNDButton.Text = 'RND';
            app.RNDButton_2 = uibutton(app.InputPanel, 'push');
            app.RNDButton_2.ButtonPushedFcn = createCallbackFcn(app, @RNDButton_2Pushed, true);
            app.RNDButton_2.Text = 'RND';
            app.RNDButton_3 = uibutton(app.InputPanel, 'push');
            app.RNDButton_3.ButtonPushedFcn = createCallbackFcn(app, @RNDButton_3Pushed, true);
            app.RNDButton_3.Text = 'RND';
            app.RNDButton_4 = uibutton(app.InputPanel, 'push');
            app.RNDButton_4.ButtonPushedFcn = createCallbackFcn(app, @RNDButton_4Pushed, true);
            app.RNDButton_4.Text = 'RND';
            app.MiscDataPanel = uipanel(app.InputPanel);
            app.MiscDataPanel.Title = 'Misc. Data';
            app.RandomMiscCheckBox = uicheckbox(app.MiscDataPanel);
            app.RandomMiscCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomMiscCheckBoxValueChanged, true);
            app.RandomMiscCheckBox.Text = 'Random Misc.';
            app.REditField_3Label = uilabel(app.MiscDataPanel);
            app.REditField_3Label.Text = 'R';
            app.REditField_3 = uieditfield(app.MiscDataPanel, 'text');
            app.REditField_3.Value = '0,0';
            app.GEditField_3Label = uilabel(app.MiscDataPanel);
            app.GEditField_3Label.Text = 'G';
            app.GEditField_3 = uieditfield(app.MiscDataPanel, 'text');
            app.GEditField_3.Value = '0,1';
            app.BEditField_3Label = uilabel(app.MiscDataPanel);
            app.BEditField_3Label.Text = 'B';
            app.BEditField_3 = uieditfield(app.MiscDataPanel, 'text');
            app.BEditField_3.Value = '1,0';
            app.VEditField_3Label = uilabel(app.MiscDataPanel);
            app.VEditField_3Label.Text = 'V';
            app.VEditField_3 = uieditfield(app.MiscDataPanel, 'text');
            app.VEditField_3.Value = '1,1';
            app.CalCurveButtonGroup = uibuttongroup(app.InputPanel);
            app.CalCurveButtonGroup.Title = 'Cal. Curve';
            app.NoneButton = uiradiobutton(app.CalCurveButtonGroup);
            app.NoneButton.Text = 'None';
            app.bitplevelButton_2 = uiradiobutton(app.CalCurveButtonGroup);
            app.bitplevelButton_2.Text = '1 bit p/ level';
            app.bitsplevelButton = uiradiobutton(app.CalCurveButtonGroup);
            app.bitsplevelButton.Text = '2 bits p/ level';
            app.bitsplevelButton.Value = true;
            % Show the figure after all components are created
            app.FrameGenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrameGen_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FrameGenUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FrameGenUIFigure)
        end
    end
end