classdef SimVLC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimVLCUIFigure               matlab.ui.Figure
        SelectedFileEditField        matlab.ui.control.EditField
        SelectedFileEditFieldLabel   matlab.ui.control.Label
        ChooseFileButton             matlab.ui.control.StateButton
        Lamp_2                       matlab.ui.control.Lamp
        Lamp                         matlab.ui.control.Lamp
        Switch                       matlab.ui.control.Switch
        Label_12                     matlab.ui.control.Label
        ShowBERatOutputCheckBox      matlab.ui.control.CheckBox
        ScenariosPanel               matlab.ui.container.Panel
        Scenario3UserBButton         matlab.ui.control.Button
        Scenario3UserAButton         matlab.ui.control.Button
        Scenario2t1Button            matlab.ui.control.Button
        Scenario2t0Button            matlab.ui.control.Button
        Scenario1Button              matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        SamplesEditField             matlab.ui.control.NumericEditField
        SamplesEditFieldLabel        matlab.ui.control.Label
        CurvingRateEditField         matlab.ui.control.NumericEditField
        CurvingRateLabel             matlab.ui.control.Label
        CapacitiveBehaviourCheckBox  matlab.ui.control.CheckBox
        LengthLabel                  matlab.ui.control.Label
        EditField_12                 matlab.ui.control.NumericEditField
        EditField_11                 matlab.ui.control.NumericEditField
        EditField_10                 matlab.ui.control.NumericEditField
        EditField_9                  matlab.ui.control.NumericEditField
        EditField_8                  matlab.ui.control.NumericEditField
        EditField_7                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        RefreshLengthButton          matlab.ui.control.Button
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField_1                  matlab.ui.control.NumericEditField
        RunningLamp                  matlab.ui.control.Lamp
        RunningLampLabel             matlab.ui.control.Label
        STARTButton                  matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        ParityCheckBox               matlab.ui.control.CheckBox
        OpticalInterferenceDropDown  matlab.ui.control.DropDown
        OpticalInterferenceDropDownLabel  matlab.ui.control.Label
        ModulationDropDown           matlab.ui.control.DropDown
        ModulationDropDownLabel      matlab.ui.control.Label
        ClearButton                  matlab.ui.control.Button
        CMS3CheckBox                 matlab.ui.control.CheckBox
        S3CheckBox                   matlab.ui.control.CheckBox
        CMS2CheckBox                 matlab.ui.control.CheckBox
        CMS1CheckBox                 matlab.ui.control.CheckBox
        S2CheckBox                   matlab.ui.control.CheckBox
        P1CheckBox                   matlab.ui.control.CheckBox
        SimulationCheckBox           matlab.ui.control.CheckBox
        CloseGraphsButton            matlab.ui.control.Button
        CCGraphsCheckBox             matlab.ui.control.CheckBox
        Label_11                     matlab.ui.control.Label
        CalibrationCurveLabel        matlab.ui.control.Label
        Label_10                     matlab.ui.control.Label
        FileName3EditField           matlab.ui.control.EditField
        FileName3EditFieldLabel      matlab.ui.control.Label
        FileName2EditField           matlab.ui.control.EditField
        FileName2EditFieldLabel      matlab.ui.control.Label
        CMVS3EditField               matlab.ui.control.EditField
        CMVS3EditFieldLabel          matlab.ui.control.Label
        CMBS3EditField               matlab.ui.control.EditField
        CMBS3EditFieldLabel          matlab.ui.control.Label
        CMGS3EditField               matlab.ui.control.EditField
        CMGS3EditFieldLabel          matlab.ui.control.Label
        CMRS3EditField               matlab.ui.control.EditField
        CMRS3EditFieldLabel          matlab.ui.control.Label
        CMVS2EditField               matlab.ui.control.EditField
        CMVS2EditFieldLabel          matlab.ui.control.Label
        CMBS2EditField               matlab.ui.control.EditField
        CMBS2EditFieldLabel          matlab.ui.control.Label
        CMGS2EditField               matlab.ui.control.EditField
        CMGS2EditFieldLabel          matlab.ui.control.Label
        CMRS2EditField               matlab.ui.control.EditField
        CMRS2EditFieldLabel          matlab.ui.control.Label
        CMVSEditField                matlab.ui.control.EditField
        CMVSEditFieldLabel           matlab.ui.control.Label
        CMBSEditField                matlab.ui.control.EditField
        CMBSEditFieldLabel           matlab.ui.control.Label
        CMGSEditField                matlab.ui.control.EditField
        CMGSEditFieldLabel           matlab.ui.control.Label
        CMRSEditField                matlab.ui.control.EditField
        CMRSEditFieldLabel           matlab.ui.control.Label
        TransmittedSignalCMLabel     matlab.ui.control.Label
        VS3EditField                 matlab.ui.control.EditField
        VS3EditFieldLabel            matlab.ui.control.Label
        BS3EditField                 matlab.ui.control.EditField
        BS3EditFieldLabel            matlab.ui.control.Label
        GS3EditField                 matlab.ui.control.EditField
        GS3EditFieldLabel            matlab.ui.control.Label
        RS3EditField                 matlab.ui.control.EditField
        RS3EditFieldLabel            matlab.ui.control.Label
        VS2EditField                 matlab.ui.control.EditField
        VS2EditFieldLabel            matlab.ui.control.Label
        BS2EditField                 matlab.ui.control.EditField
        BS2EditFieldLabel            matlab.ui.control.Label
        GS2EditField                 matlab.ui.control.EditField
        GS2EditFieldLabel            matlab.ui.control.Label
        RS2EditField                 matlab.ui.control.EditField
        RS2EditFieldLabel            matlab.ui.control.Label
        VEditField                   matlab.ui.control.EditField
        VEditFieldLabel              matlab.ui.control.Label
        BEditField                   matlab.ui.control.EditField
        BEditFieldLabel              matlab.ui.control.Label
        GEditField                   matlab.ui.control.EditField
        GEditFieldLabel              matlab.ui.control.Label
        REditField                   matlab.ui.control.EditField
        REditFieldLabel              matlab.ui.control.Label
        DataFrameLabel               matlab.ui.control.Label
        Tempo                        matlab.ui.control.NumericEditField
        BitTimemsLabel               matlab.ui.control.Label
        Grx_V                        matlab.ui.control.NumericEditField
        Grx_B                        matlab.ui.control.NumericEditField
        Grx_G                        matlab.ui.control.NumericEditField
        Grx_R                        matlab.ui.control.NumericEditField
        GaindBLabel                  matlab.ui.control.Label
        lambda_V                     matlab.ui.control.NumericEditField
        lambda_B                     matlab.ui.control.NumericEditField
        lambda_G                     matlab.ui.control.NumericEditField
        lambda_R                     matlab.ui.control.NumericEditField
        WavelengthnmLabel            matlab.ui.control.Label
        LEDRadiuscmEditField         matlab.ui.control.NumericEditField
        LEDRadiuscmEditFieldLabel    matlab.ui.control.Label
        LED4_V                       matlab.ui.control.NumericEditField
        V4Label                      matlab.ui.control.Label
        LED4_B                       matlab.ui.control.NumericEditField
        B4Label                      matlab.ui.control.Label
        LED4_G                       matlab.ui.control.NumericEditField
        G4Label                      matlab.ui.control.Label
        LED4_R                       matlab.ui.control.NumericEditField
        R4Label                      matlab.ui.control.Label
        LED3_V                       matlab.ui.control.NumericEditField
        V3Label                      matlab.ui.control.Label
        LED3_B                       matlab.ui.control.NumericEditField
        B3Label                      matlab.ui.control.Label
        LED3_G                       matlab.ui.control.NumericEditField
        G3Label                      matlab.ui.control.Label
        LED3_R                       matlab.ui.control.NumericEditField
        R3Label                      matlab.ui.control.Label
        LED2_V                       matlab.ui.control.NumericEditField
        V2Label                      matlab.ui.control.Label
        LED1_B                       matlab.ui.control.NumericEditField
        B1Label                      matlab.ui.control.Label
        LED2_G                       matlab.ui.control.NumericEditField
        G2Label                      matlab.ui.control.Label
        LED2_R                       matlab.ui.control.NumericEditField
        R2Label                      matlab.ui.control.Label
        VIOLETLEDLabel               matlab.ui.control.Label
        BLUELEDLabel                 matlab.ui.control.Label
        GREENLEDLabel                matlab.ui.control.Label
        REDLEDLabel                  matlab.ui.control.Label
        LED1_V                       matlab.ui.control.NumericEditField
        V1Label                      matlab.ui.control.Label
        LED2_B                       matlab.ui.control.NumericEditField
        B2Label                      matlab.ui.control.Label
        LED1_G                       matlab.ui.control.NumericEditField
        G1Label                      matlab.ui.control.Label
        LED1_R                       matlab.ui.control.NumericEditField
        R1Label                      matlab.ui.control.Label
        SistemaLiFiNavegaoassistidaporLEDsLabel  matlab.ui.control.Label
        TransmissionPowerdBmLabel    matlab.ui.control.Label
        DecodingCheckBox             matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
    
    end
    
    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function
        function RunButtonPushed(app, event)

            clc; close all;
            
            Pt_R1=app.LED1_R.Value;
            Pt_G1=app.LED1_G.Value;
            Pt_B1=app.LED2_B.Value;
            Pt_V1=app.LED1_V.Value; 
            
            Pt_R2=app.LED2_R.Value;
            Pt_B2=app.LED1_B.Value;
            Pt_V2=app.LED2_V.Value; 
            
            Pt_B3=app.LED3_B.Value;
            
            Pt_B4=app.LED4_B.Value;

            Raio=app.LEDRadiuscmEditField.Value;
            
            Lambda_R=app.lambda_R.Value;
            Lambda_G=app.lambda_G.Value;
            Lambda_B=app.lambda_B.Value;
            Lambda_V=app.lambda_V.Value;
            
            GanhoRx_R=app.Grx_R.Value;
            GanhoRx_G=app.Grx_G.Value;
            GanhoRx_B=app.Grx_B.Value;
            GanhoRx_V=app.Grx_V.Value;
            DeltaT=app.Tempo.Value;
            
            %MODULATION
            mod_select = app.ModulationDropDown.Value;
            
            %CAPACITIVE BEHAVIOR
            capacitive_select = app.CapacitiveBehaviourCheckBox.Value;
            capacitive_howmuch = app.CurvingRateEditField.Value;
            capacitive_samples = app.SamplesEditField.Value;
            
            %PARITY
            parity_select = app.ParityCheckBox.Value;

            %É AQUI QUE TENTO ADAPTAR O CÓDIGO DO RODRIGUES PARA RESOLVER O
            %PROBLEMA DAS DIMENSÕES DOS ARRAYS PARA AS OUTRAS MODULAÇÕES:
            
            RS1_length = length(erase(app.REditField.Value,{'[',']',' ',','}));
            GS1_length = length(erase(app.GEditField.Value,{'[',']',' ',','}));
            BS1_length = length(erase(app.BEditField.Value,{'[',']',' ',','}));
            VS1_length = length(erase(app.VEditField.Value,{'[',']',' ',','}));
            if (RS1_length ~= GS1_length) && (RS1_length ~= BS1_length) && (RS1_length ~= VS1_length)  
                error('Input bit sequences must all have the same length!');
            end

            if strcmp(mod_select,'OOK')
                bit_length = RS1_length;
            else
                if strcmp(mod_select,'Manchester') || strcmp(mod_select,'2-PPM')
                    bit_length = RS1_length*2;
                else
                    error(['ERROR: Modulation scheme ',mod_select,' has not been implemented!']);
                end
            end
            
            SR = zeros(3,bit_length);
            SG = zeros(3,bit_length);
            SB = zeros(3,bit_length);
            SV = zeros(3,bit_length);
            CM_SR = zeros(3,bit_length);
            CM_SG = zeros(3,bit_length);
            CM_SB = zeros(3,bit_length);
            CM_SV = zeros(3,bit_length);
            
            SR_D(1,:)='00000000000000000000000000000000';
            SG_D(1,:)='00000000000000000000000000000000';
            SB_D(1,:)='00000000000000000000000000000000';
            SV_D(1,:)='00000000000000000000000000000000';
            
            %MODE
            if strcmp(app.Switch.Value,'Simulation')
                lab_mode = 0;
                simulation_mode = 1;
            else
                lab_mode = 1;
                simulation_mode = 0;
            end
            
            if lab_mode == 1 && simulation_mode == 1
            %if app.DecodingCheckBox.Value == 1 && app.SimulationCheckBox.Value == 1
                srd_temp=app.REditField.Value;
                srd_temp2=erase(srd_temp,{'[',']',' ',','});
                SR_D(1,:)=srd_temp2;
                
                sgd_temp=app.GEditField.Value;
                sgd_temp2=erase(sgd_temp,{'[',']',' ',','});
                SG_D(1,:)=sgd_temp2;
                
                sbd_temp=app.BEditField.Value;
                sbd_temp2=erase(sbd_temp,{'[',']',' ',','});
                SB_D(1,:)=sbd_temp2;
                
                svd_temp=app.VEditField.Value;
                svd_temp2=erase(svd_temp,{'[',']',' ',','});
                SV_D(1,:)=svd_temp2;
            else
            
                sr1_temp=app.REditField.Value;
                sr1_temp2=erase(sr1_temp,{'[',']',' '});
                sr1_temp=split(sr1_temp2,{','});
                input_bits_R1 = str2double(sr1_temp);
                SR(1,:) = tfm_modulation(input_bits_R1,mod_select,0);
                
                sr2_temp=app.RS2EditField.Value;
                sr2_temp2=erase(sr2_temp,{'[',']',' '});
                sr2_temp=split(sr2_temp2,{','});
                input_bits_R2 = str2double(sr2_temp);
                if input_bits_R2 ~= 0
                    SR(2,:) = tfm_modulation(input_bits_R2,mod_select,0);
                end
                
                sr3_temp=app.RS3EditField.Value;
                sr3_temp2=erase(sr3_temp,{'[',']',' '});
                sr3_temp=split(sr3_temp2,{','});
                input_bits_R3 = str2double(sr3_temp);
                if input_bits_R3 ~= 0
                    SR(3,:) = tfm_modulation(input_bits_R3,mod_select,0);
                end
                
                sg1_temp=app.GEditField.Value;
                sg1_temp2=erase(sg1_temp,{'[',']',' '});
                sg1_temp=split(sg1_temp2,{','});
                input_bits_G1 = str2double(sg1_temp);
                SG(1,:) = tfm_modulation(input_bits_G1,mod_select,0);
                
                sg2_temp=app.GS2EditField.Value;
                sg2_temp2=erase(sg2_temp,{'[',']',' '});
                sg2_temp=split(sg2_temp2,{','});
                input_bits_G2 = str2double(sg2_temp);
                if input_bits_G2 ~= 0
                    SG(2,:) = tfm_modulation(input_bits_G2,mod_select,0);
                end
                
                sg3_temp=app.GS3EditField.Value;
                sg3_temp2=erase(sg3_temp,{'[',']',' '});
                sg3_temp=split(sg3_temp2,{','});
                input_bits_G3 = str2double(sg3_temp);
                if input_bits_G3 ~= 0
                    SG(3,:) = tfm_modulation(input_bits_G3,mod_select,0);
                end
                
                sb1_temp=app.BEditField.Value;
                sb1_temp2=erase(sb1_temp,{'[',']',' '});
                sb1_temp=split(sb1_temp2,{','});
                input_bits_B1 = str2double(sb1_temp);
                SB(1,:) = tfm_modulation(input_bits_B1,mod_select,0);
                
                sb2_temp=app.BS2EditField.Value;
                sb2_temp2=erase(sb2_temp,{'[',']',' '});
                sb2_temp=split(sb2_temp2,{','});
                input_bits_B2 = str2double(sb2_temp);
                if input_bits_B2 ~= 0
                    SB(2,:) = tfm_modulation(input_bits_B2,mod_select,0);
                end
                
                sb3_temp=app.BS3EditField.Value;
                sb3_temp2=erase(sb3_temp,{'[',']',' '});
                sb3_temp=split(sb3_temp2,{','});
                input_bits_B3 = str2double(sb3_temp);
                if input_bits_B3 ~= 0
                    SB(3,:) = tfm_modulation(input_bits_B3,mod_select,0);
                end
                
                sv1_temp=app.VEditField.Value;
                sv1_temp2=erase(sv1_temp,{'[',']',' '});
                sv1_temp=split(sv1_temp2,{','});
                input_bits_V1 = str2double(sv1_temp);
                SV(1,:) = tfm_modulation(input_bits_V1,mod_select,0);
                
                sv2_temp=app.VS2EditField.Value;
                sv2_temp2=erase(sv2_temp,{'[',']',' '});
                sv2_temp=split(sv2_temp2,{','});
                input_bits_V2 = str2double(sv2_temp);
                if input_bits_V2 ~= 0
                    SV(2,:) = tfm_modulation(input_bits_V2,mod_select,0);
                end
                
                sv3_temp=app.VS3EditField.Value;
                sv3_temp2=erase(sv3_temp,{'[',']',' '});
                sv3_temp=split(sv3_temp2,{','});
                input_bits_V3 = str2double(sv3_temp);
                if input_bits_V3 ~= 0
                    SV(3,:) = tfm_modulation(input_bits_V3,mod_select,0);
                end
                
                cm_sr1_temp=app.CMRSEditField.Value;
                cm_sr1_temp2=erase(cm_sr1_temp,{'[',']',' '});
                cm_sr1_temp=split(cm_sr1_temp2,{','});
                CM_SR(1,:) = str2double(cm_sr1_temp)';
                
                cm_sr2_temp=app.CMRS2EditField.Value;
                cm_sr2_temp2=erase(cm_sr2_temp,{'[',']',' '});
                cm_sr2_temp=split(cm_sr2_temp2,{','});
                CM_SR(2,:) = str2double(cm_sr2_temp)';
                
                cm_sr3_temp=app.CMRS3EditField.Value;
                cm_sr3_temp2=erase(cm_sr3_temp,{'[',']',' '});
                cm_sr3_temp=split(cm_sr3_temp2,{','});
                CM_SR(3,:) = str2double(cm_sr3_temp)';
                
                cm_sg1_temp=app.CMGSEditField.Value;
                cm_sg1_temp2=erase(cm_sg1_temp,{'[',']',' '});
                cm_sg1_temp=split(cm_sg1_temp2,{','});
                CM_SG(1,:) = str2double(cm_sg1_temp)';
                
                cm_sg2_temp=app.CMGS2EditField.Value;
                cm_sg2_temp2=erase(cm_sg2_temp,{'[',']',' '});
                cm_sg2_temp=split(cm_sg2_temp2,{','});
                CM_SG(2,:) = str2double(cm_sg2_temp)';
                
                cm_sg3_temp=app.CMGS3EditField.Value;
                cm_sg3_temp2=erase(cm_sg3_temp,{'[',']',' '});
                cm_sg3_temp=split(cm_sg3_temp2,{','});
                CM_SG(3,:) = str2double(cm_sg3_temp)';
                
                cm_sb1_temp=app.CMBSEditField.Value;
                cm_sb1_temp2=erase(cm_sb1_temp,{'[',']',' '});
                cm_sb1_temp=split(cm_sb1_temp2,{','});
                CM_SB(1,:) = str2double(cm_sb1_temp)';
                
                cm_sb2_temp=app.CMBS2EditField.Value;
                cm_sb2_temp2=erase(cm_sb2_temp,{'[',']',' '});
                cm_sb2_temp=split(cm_sb2_temp2,{','});
                CM_SB(2,:) = str2double(cm_sb2_temp)';
                
                cm_sb3_temp=app.CMBS3EditField.Value;
                cm_sb3_temp2=erase(cm_sb3_temp,{'[',']',' '});
                cm_sb3_temp=split(cm_sb3_temp2,{','});
                CM_SB(3,:) = str2double(cm_sb3_temp)';
                
                cm_sv1_temp=app.CMVSEditField.Value;
                cm_sv1_temp2=erase(cm_sv1_temp,{'[',']',' '});
                cm_sv1_temp=split(cm_sv1_temp2,{','});
                CM_SV(1,:) = str2double(cm_sv1_temp)';
                
                cm_sv2_temp=app.CMVS2EditField.Value;
                cm_sv2_temp2=erase(cm_sv2_temp,{'[',']',' '});
                cm_sv2_temp=split(cm_sv2_temp2,{','});
                CM_SV(2,:) = str2double(cm_sv2_temp)';
                
                cm_sv3_temp=app.CMVS3EditField.Value;
                cm_sv3_temp2=erase(cm_sv3_temp,{'[',']',' '});
                cm_sv3_temp=split(cm_sv3_temp2,{','});
                CM_SV(3,:) = str2double(cm_sv3_temp)';
            end
            
            nf1=app.SelectedFileEditField.Value;
            nf2=app.FileName2EditField.Value;
            nf3=app.FileName3EditField.Value;
            
            if strcmp(nf1,'NULL')==1
               nf1="";
            end
            if strcmp(nf2,'NULL')==1
                nf2="";
            end
            if strcmp(nf3,'NULL')==1
                nf3="";
            end
            
            OFFcount=0;
            if simulation_mode == 0
            %if app.SimulationCheckBox.Value==0
                OFFcount=OFFcount+1;
            end
            if app.S2CheckBox.Value == 0
                OFFcount=OFFcount+1;
            end
            if app.S3CheckBox.Value == 0
                OFFcount=OFFcount+1;    
            end
            
            CM_OFFcount=0;
            if app.CMS1CheckBox.Value == 0
                CM_OFFcount=CM_OFFcount+1;
            end
            if app.CMS2CheckBox.Value == 0
                CM_OFFcount=CM_OFFcount+1;
            end
            if app.CMS3CheckBox.Value == 0
                CM_OFFcount=CM_OFFcount+1;
            end
            
            P_OFF = xor(0,app.P1CheckBox.Value);
            D_ON = lab_mode;
            %D_ON = app.DecodingCheckBox.Value;
            BER = app.ShowBERatOutputCheckBox.Value;
            CC_OFF = app.CCGraphsCheckBox.Value;
            
            imported_excel = app.SelectedFileEditField.Value;
            
            LiFi([Pt_R1,Pt_R2],Pt_G1,[Pt_B1,Pt_B2,Pt_B3,Pt_B4],...
                [Pt_V1,Pt_V2], Raio,Lambda_R,Lambda_G,Lambda_B,Lambda_V,...
                GanhoRx_R,GanhoRx_G,GanhoRx_B,GanhoRx_V, DeltaT,...
                SR,SG,SB,SV,CM_SR,CM_SG,CM_SB,CM_SV,nf1,nf2,nf3,...
                OFFcount,CM_OFFcount,P_OFF,D_ON,SR_D,SG_D,SB_D,SV_D,...
                BER,CC_OFF,mod_select,parity_select,...
                capacitive_select,capacitive_howmuch,capacitive_samples,...
                imported_excel,...
                input_bits_R1,input_bits_G1,input_bits_B1,input_bits_V1);
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'On') 
                app.Lamp.Color='g';
                app.RunButtonPushed;  
            else
                app.Lamp.Color='r';
                return 
            end
        end

        % Button pushed function: CloseGraphsButton
        function CloseGraphsButtonPushed(app, event)
            close all;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clc;
        end

        % Callback function
        function Switch_2ValueChanged(app, event)
            app.Lamp_2.Color = 'y';
            app.Lamp_3.Color = 'y';
            value = app.Switch_2.Value;
            if strcmp(value,'ON') 
                app.Lamp_2.Color = 'k';
                app.Lamp_3.Color='g';
                app.RunButtonPushed;  
            else
                app.Lamp_2.Color = 'r';
                app.Lamp_3.Color='k';
                return 
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.RunningLamp.Color = 'y';
            pause(0.5)
            app.RunButtonPushed;
            app.RunningLamp.Color = 'k';
        end

        % Callback function
        function HELPButtonPushed(app, event)
            tfm49_HelpWindow;
        end

        % Button pushed function: RefreshLengthButton
        function RefreshLengthButtonPushed(app, event)
            app.EditField_1.Value = length(erase(app.REditField.Value,{'[',']',' ',','}));
            app.EditField_2.Value = length(erase(app.GEditField.Value,{'[',']',' ',','}));
            app.EditField_3.Value = length(erase(app.BEditField.Value,{'[',']',' ',','}));
            app.EditField_4.Value = length(erase(app.VEditField.Value,{'[',']',' ',','}));
        end

        % Value changed function: OpticalInterferenceDropDown
        function OpticalInterferenceDropDownValueChanged(app, event)
            value = app.OpticalInterferenceDropDown.Value;
            None.R = 4.49;
            None.G = 3.94;
            None.B = 1.9;
            None.V = 0.81;
            if strcmp(value,'None')
                app.Grx_R.Value = None.R;
                app.Grx_G.Value = None.G;
                app.Grx_B.Value = None.B;
                app.Grx_V.Value = None.V;
            else
                if strcmp(value,'Halogen Light')
                    app.Grx_R.Value = None.R*0.96;
                    app.Grx_G.Value = None.G*0.91;
                    app.Grx_B.Value = None.B*0.887;
                    app.Grx_V.Value = None.V*1.69;
                else
                    if strcmp(value,'Fluorescent Light')
                        app.Grx_R.Value = None.R*0.98;
                        app.Grx_G.Value = None.G*0.96;
                        app.Grx_B.Value = None.B*0.93;
                        app.Grx_V.Value = None.V*1.31;
                    else
                        if strcmp(value,'LED Light')
                            app.Grx_R.Value = None.R*0.97;
                            app.Grx_G.Value = None.G*0.94;
                            app.Grx_B.Value = None.B*0.89;
                            app.Grx_V.Value = None.V*0.73;
                        else
                            error(['Unrecognised interference type ',value]);
                        end
                    end
                end
            end
        end

        % Callback function
        function EncodingTestButtonPushed(app, event)
            %
            app.REditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1';
            app.GEditField.Value = '1,0,1,0,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1';
            app.BEditField.Value = '1,0,1,0,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1';
            app.VEditField.Value = '1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1';
        end

        % Callback function
        function DecodingTestButtonPushed(app, event)
            %
            app.REditField.Value = '1,0,1,1,0,1,0,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0,1,0';
            app.GEditField.Value = '1,0,0,1,0,0,1,0,0,1,0,0,1,0,0,1,0,0,1,0,0,1,0,0,1,0,0,1,0,0,1,0';
            app.BEditField.Value = '1,0,0,0,1,0,0,0,1,0,0,0,1,0,0,0,1,0,0,0,1,0,0,0,1,0,0,0,1,0,0,0';
            app.VEditField.Value = '1,0,0,0,0,1,0,0,0,0,1,0,0,0,0,1,0,0,0,0,1,0,0,0,0,1,0,0,0,0,1,0';
        end

        % Value changed function: ChooseFileButton
        function ChooseFileButtonValueChanged(app, event)
            [imported_file,imported_path] = uigetfile('*.xls');  %open a xls file
            imported_excel = [imported_path imported_file];
            app.SelectedFileEditField.Value = imported_excel;
        end

        % Callback function
        function NULLButtonPushed(app, event)
            app.SelectedFileEditField.Value = 'NULL';
        end

        % Button pushed function: Scenario1Button
        function Scenario1ButtonPushed(app, event)
            app.REditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,0,0,1,1,0,1,0,0,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.GEditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,1,0,0,0,1,0,0,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.BEditField.Value = '1,0,1,0,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,1,0,0,0,0,1,1,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.VEditField.Value = '1,0,1,0,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
        end

        % Button pushed function: Scenario2t0Button
        function Scenario2t0ButtonPushed(app, event)
            app.REditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,0,0,1,1,0,1,0,0,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.GEditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,1,0,0,0,1,0,0,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.BEditField.Value = '1,0,1,0,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,1,0,0,0,0,1,1,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.VEditField.Value = '1,0,1,0,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
        end

        % Button pushed function: Scenario2t1Button
        function Scenario2t1ButtonPushed(app, event)
            app.REditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,0,0,1,1,0,1,0,0,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.GEditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,0,1,0,0,1,0,0,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.BEditField.Value = '1,0,1,0,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,1,0,0,0,1,1,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.VEditField.Value = '1,0,1,0,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
        end

        % Button pushed function: Scenario3UserAButton
        function Scenario3UserAButtonPushed(app, event)
            app.REditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,0,0,1,1,0,1,0,0,0,0,0,1,0,1,0,0,0,1,1,0,0,0,1,1,0,0,0';
            app.GEditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,0,1,0,0,1,0,0,0,0,0,1,0,0,0,1,1,0,0,0,0,0,1,1,0,0,0';
            app.BEditField.Value = '1,0,1,0,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,1,0,0,0,1,1,0,0,0,1,1,0,0,1,0,1,0,1,0,0,1,1,0,0,0';
            app.VEditField.Value = '1,0,1,0,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,0,1,0,1,1,1,0,0,1,1,0,0,1,1,0,0,0';
        end

        % Button pushed function: Scenario3UserBButton
        function Scenario3UserBButtonPushed(app, event)
            app.REditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,0,0,1,1,0,0,1,0,0,0,0,1,0,1,0,0,0,1,1,0,0,1,0,1,0,0,0';
            app.GEditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,1,0,0,0,0,1,0,0,0,0,1,0,1,1,0,1,0,0,0,0,1,0,1,0,0,0';
            app.BEditField.Value = '1,0,1,0,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,1,0,0,0,0,0,1,0,0,0,1,0,1,1,1,0,1,0,1,0,1,0,1,0,0,0';
            app.VEditField.Value = '1,0,1,0,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,0,1,0,0,0,1,1,0,0,1,0,0,1,1,0,1,0,1,0,0,0';
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Simulation')
                %simulation_mode = 1;
                %lab_mode = 0;
                app.Lamp.Color = 'g';
                pause(0.0)
                app.Lamp_2.Color = 'k';
            else
                %simulation_mode = 0;
                %lab_mode = 1;
                app.Lamp_2.Color = 'g';
                pause(0.0)
                app.Lamp.Color = 'k';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimVLCUIFigure = uifigure('Visible', 'off');
            app.SimVLCUIFigure.Name = 'SimVLC';
            app.DecodingCheckBox = uicheckbox(app.SimVLCUIFigure);
            app.DecodingCheckBox.Visible = 'off';
            app.DecodingCheckBox.Text = 'Decoding';
            app.TransmissionPowerdBmLabel = uilabel(app.SimVLCUIFigure);
            app.TransmissionPowerdBmLabel.Visible = 'off';
            app.TransmissionPowerdBmLabel.Text = 'Transmission Power [dBm]';
            app.SistemaLiFiNavegaoassistidaporLEDsLabel = uilabel(app.SimVLCUIFigure);
            app.SistemaLiFiNavegaoassistidaporLEDsLabel.Text = 'Dynamic Navigation System in Indoor Environments Using Visible Light';
            app.R1Label = uilabel(app.SimVLCUIFigure);
            app.R1Label.Visible = 'off';
            app.R1Label.Text = 'R1';
            app.LED1_R = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED1_R.Limits = [0 50];
            app.LED1_R.ValueDisplayFormat = '%5.2f';
            app.LED1_R.Visible = 'off';
            app.LED1_R.Value = 13.5;
            app.G1Label = uilabel(app.SimVLCUIFigure);
            app.G1Label.Visible = 'off';
            app.G1Label.Text = 'G1';
            app.LED1_G = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED1_G.Limits = [0 50];
            app.LED1_G.ValueDisplayFormat = '%5.2f';
            app.LED1_G.Visible = 'off';
            app.LED1_G.Value = 13;
            app.B2Label = uilabel(app.SimVLCUIFigure);
            app.B2Label.Visible = 'off';
            app.B2Label.Text = 'B2';
            app.LED2_B = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED2_B.Limits = [0 50];
            app.LED2_B.ValueDisplayFormat = '%5.2f';
            app.LED2_B.Visible = 'off';
            app.V1Label = uilabel(app.SimVLCUIFigure);
            app.V1Label.Visible = 'off';
            app.V1Label.Text = 'V1';
            app.LED1_V = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED1_V.Limits = [0 50];
            app.LED1_V.ValueDisplayFormat = '%5.2f';
            app.LED1_V.Visible = 'off';
            app.LED1_V.Value = 14.1;
            app.REDLEDLabel = uilabel(app.SimVLCUIFigure);
            app.REDLEDLabel.Visible = 'off';
            app.REDLEDLabel.Text = 'RED LED';
            app.GREENLEDLabel = uilabel(app.SimVLCUIFigure);
            app.GREENLEDLabel.Visible = 'off';
            app.GREENLEDLabel.Text = 'GREEN LED';
            app.BLUELEDLabel = uilabel(app.SimVLCUIFigure);
            app.BLUELEDLabel.Visible = 'off';
            app.BLUELEDLabel.Text = 'BLUE LED';
            app.VIOLETLEDLabel = uilabel(app.SimVLCUIFigure);
            app.VIOLETLEDLabel.Visible = 'off';
            app.VIOLETLEDLabel.Text = 'VIOLET LED';
            app.R2Label = uilabel(app.SimVLCUIFigure);
            app.R2Label.Visible = 'off';
            app.R2Label.Text = 'R2';
            app.LED2_R = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED2_R.Limits = [0 50];
            app.LED2_R.ValueDisplayFormat = '%5.2f';
            app.LED2_R.Visible = 'off';
            app.LED2_R.Value = 13.5;
            app.G2Label = uilabel(app.SimVLCUIFigure);
            app.G2Label.Visible = 'off';
            app.G2Label.Text = 'G2';
            app.LED2_G = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED2_G.Limits = [0 0.001];
            app.LED2_G.ValueDisplayFormat = '%5.2f';
            app.LED2_G.Visible = 'off';
            app.B1Label = uilabel(app.SimVLCUIFigure);
            app.B1Label.Visible = 'off';
            app.B1Label.Text = 'B1';
            app.LED1_B = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED1_B.Limits = [0 50];
            app.LED1_B.ValueDisplayFormat = '%5.2f';
            app.LED1_B.Visible = 'off';
            app.LED1_B.Value = 13.5;
            app.V2Label = uilabel(app.SimVLCUIFigure);
            app.V2Label.Visible = 'off';
            app.V2Label.Text = 'V2';
            app.LED2_V = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED2_V.Limits = [0 50];
            app.LED2_V.ValueDisplayFormat = '%5.2f';
            app.LED2_V.Visible = 'off';
            app.R3Label = uilabel(app.SimVLCUIFigure);
            app.R3Label.Visible = 'off';
            app.R3Label.Text = 'R3';
            app.LED3_R = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED3_R.Limits = [0 0.1];
            app.LED3_R.RoundFractionalValues = 'on';
            app.LED3_R.ValueDisplayFormat = '%5.2f';
            app.LED3_R.Visible = 'off';
            app.G3Label = uilabel(app.SimVLCUIFigure);
            app.G3Label.Visible = 'off';
            app.G3Label.Text = 'G3';
            app.LED3_G = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED3_G.Limits = [0 0.001];
            app.LED3_G.ValueDisplayFormat = '%5.2f';
            app.LED3_G.Visible = 'off';
            app.B3Label = uilabel(app.SimVLCUIFigure);
            app.B3Label.Visible = 'off';
            app.B3Label.Text = 'B3';
            app.LED3_B = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED3_B.Limits = [0 50];
            app.LED3_B.ValueDisplayFormat = '%5.2f';
            app.LED3_B.Visible = 'off';
            app.LED3_B.Value = 13.5;
            app.V3Label = uilabel(app.SimVLCUIFigure);
            app.V3Label.Visible = 'off';
            app.V3Label.Text = 'V3';
            app.LED3_V = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED3_V.Limits = [0 0.001];
            app.LED3_V.ValueDisplayFormat = '%5.2f';
            app.LED3_V.Visible = 'off';
            app.R4Label = uilabel(app.SimVLCUIFigure);
            app.R4Label.Visible = 'off';
            app.R4Label.Text = 'R4';
            app.LED4_R = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED4_R.Limits = [0 0.001];
            app.LED4_R.ValueDisplayFormat = '%5.2f';
            app.LED4_R.Visible = 'off';
            app.G4Label = uilabel(app.SimVLCUIFigure);
            app.G4Label.Visible = 'off';
            app.G4Label.Text = 'G4';
            app.LED4_G = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED4_G.Limits = [0 0.001];
            app.LED4_G.ValueDisplayFormat = '%5.2f';
            app.LED4_G.Visible = 'off';
            app.B4Label = uilabel(app.SimVLCUIFigure);
            app.B4Label.Visible = 'off';
            app.B4Label.Text = 'B4';
            app.LED4_B = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED4_B.Limits = [0 50];
            app.LED4_B.ValueDisplayFormat = '%5.2f';
            app.LED4_B.Visible = 'off';
            app.V4Label = uilabel(app.SimVLCUIFigure);
            app.V4Label.Visible = 'off';
            app.V4Label.Text = 'V4';
            app.LED4_V = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LED4_V.Limits = [0 0.001];
            app.LED4_V.ValueDisplayFormat = '%5.2f';
            app.LED4_V.Visible = 'off';
            app.LEDRadiuscmEditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.LEDRadiuscmEditFieldLabel.Visible = 'off';
            app.LEDRadiuscmEditFieldLabel.Text = 'LED Radius [cm]';
            app.LEDRadiuscmEditField = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.LEDRadiuscmEditField.Limits = [0 25];
            app.LEDRadiuscmEditField.ValueDisplayFormat = '%5.2f';
            app.LEDRadiuscmEditField.Visible = 'off';
            app.LEDRadiuscmEditField.Value = 2;
            app.WavelengthnmLabel = uilabel(app.SimVLCUIFigure);
            app.WavelengthnmLabel.Visible = 'off';
            app.WavelengthnmLabel.Text = 'Wavelength [nm]';
            app.lambda_R = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.lambda_R.Limits = [0 1000];
            app.lambda_R.ValueDisplayFormat = '%.0f';
            app.lambda_R.Visible = 'off';
            app.lambda_R.Value = 624;
            app.lambda_G = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.lambda_G.Limits = [0 1000];
            app.lambda_G.ValueDisplayFormat = '%.0f';
            app.lambda_G.Visible = 'off';
            app.lambda_G.Value = 540;
            app.lambda_B = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.lambda_B.Limits = [0 1000];
            app.lambda_B.ValueDisplayFormat = '%.0f';
            app.lambda_B.Visible = 'off';
            app.lambda_B.Value = 480;
            app.lambda_V = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.lambda_V.Limits = [0 1000];
            app.lambda_V.ValueDisplayFormat = '%.0f';
            app.lambda_V.Visible = 'off';
            app.lambda_V.Value = 405;
            app.GaindBLabel = uilabel(app.SimVLCUIFigure);
            app.GaindBLabel.Text = 'Gain [dB]';
            app.Grx_R = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.Grx_R.Limits = [0 1000];
            app.Grx_R.ValueDisplayFormat = '%5.2f';
            app.Grx_R.Value = 4.49;
            app.Grx_G = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.Grx_G.Limits = [0 1000];
            app.Grx_G.ValueDisplayFormat = '%5.2f';
            app.Grx_G.Value = 3.94;
            app.Grx_B = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.Grx_B.Limits = [0 1000];
            app.Grx_B.ValueDisplayFormat = '%5.2f';
            app.Grx_B.Value = 1.9;
            app.Grx_V = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.Grx_V.Limits = [0 1000];
            app.Grx_V.ValueDisplayFormat = '%5.2f';
            app.Grx_V.Value = 0.81;
            app.BitTimemsLabel = uilabel(app.SimVLCUIFigure);
            app.BitTimemsLabel.Text = 'Bit Time [ms]';
            app.Tempo = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.Tempo.Limits = [0 25];
            app.Tempo.ValueDisplayFormat = '%5.2f';
            app.Tempo.Value = 0.08;
            app.DataFrameLabel = uilabel(app.SimVLCUIFigure);
            app.DataFrameLabel.Text = 'Data Frame';
            app.REditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.REditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,0,0,1,1,0,1,0,0,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.GEditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.GEditField.Value = '1,0,1,0,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,1,0,1,0,0,0,1,0,0,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.BEditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.BEditField.Value = '1,0,1,0,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,0,0,0,1,1,1,1,0,1,0,0,0,0,1,1,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.VEditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.VEditField.Value = '1,0,1,0,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,1,1,0,0,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0';
            app.RS2EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.RS2EditFieldLabel.Visible = 'off';
            app.RS2EditFieldLabel.Text = 'RS2';
            app.RS2EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.RS2EditField.Editable = 'off';
            app.RS2EditField.Visible = 'off';
            app.RS2EditField.Value = '0';
            app.GS2EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.GS2EditFieldLabel.Visible = 'off';
            app.GS2EditFieldLabel.Text = 'GS2';
            app.GS2EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.GS2EditField.Editable = 'off';
            app.GS2EditField.Visible = 'off';
            app.GS2EditField.Value = '0';
            app.BS2EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.BS2EditFieldLabel.Visible = 'off';
            app.BS2EditFieldLabel.Text = 'BS2';
            app.BS2EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.BS2EditField.Editable = 'off';
            app.BS2EditField.Visible = 'off';
            app.BS2EditField.Value = '0';
            app.VS2EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.VS2EditFieldLabel.Visible = 'off';
            app.VS2EditFieldLabel.Text = 'VS2';
            app.VS2EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.VS2EditField.Editable = 'off';
            app.VS2EditField.Visible = 'off';
            app.VS2EditField.Value = '0';
            app.RS3EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.RS3EditFieldLabel.Visible = 'off';
            app.RS3EditFieldLabel.Text = 'RS3';
            app.RS3EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.RS3EditField.Editable = 'off';
            app.RS3EditField.Visible = 'off';
            app.RS3EditField.Value = '0';
            app.GS3EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.GS3EditFieldLabel.Visible = 'off';
            app.GS3EditFieldLabel.Text = 'GS3';
            app.GS3EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.GS3EditField.Editable = 'off';
            app.GS3EditField.Visible = 'off';
            app.GS3EditField.Value = '0';
            app.BS3EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.BS3EditFieldLabel.Visible = 'off';
            app.BS3EditFieldLabel.Text = 'BS3';
            app.BS3EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.BS3EditField.Editable = 'off';
            app.BS3EditField.Visible = 'off';
            app.BS3EditField.Value = '0';
            app.VS3EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.VS3EditFieldLabel.Visible = 'off';
            app.VS3EditFieldLabel.Text = 'VS3';
            app.VS3EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.VS3EditField.Editable = 'off';
            app.VS3EditField.Visible = 'off';
            app.VS3EditField.Value = '0';
            app.TransmittedSignalCMLabel = uilabel(app.SimVLCUIFigure);
            app.TransmittedSignalCMLabel.Visible = 'off';
            app.TransmittedSignalCMLabel.Text = 'Transmitted Signal (CM)';
            app.CMRSEditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMRSEditFieldLabel.Visible = 'off';
            app.CMRSEditFieldLabel.Text = ' (CM) RS';
            app.CMRSEditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMRSEditField.Visible = 'off';
            app.CMRSEditField.Value = '0';
            app.CMGSEditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMGSEditFieldLabel.Visible = 'off';
            app.CMGSEditFieldLabel.Text = '(CM) GS';
            app.CMGSEditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMGSEditField.Visible = 'off';
            app.CMGSEditField.Value = '0';
            app.CMBSEditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMBSEditFieldLabel.Visible = 'off';
            app.CMBSEditFieldLabel.Text = '(CM) BS';
            app.CMBSEditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMBSEditField.Visible = 'off';
            app.CMBSEditField.Value = '0';
            app.CMVSEditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMVSEditFieldLabel.Visible = 'off';
            app.CMVSEditFieldLabel.Text = '(CM) VS';
            app.CMVSEditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMVSEditField.Visible = 'off';
            app.CMVSEditField.Value = '0';
            app.CMRS2EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMRS2EditFieldLabel.Visible = 'off';
            app.CMRS2EditFieldLabel.Text = ' (CM) RS2';
            app.CMRS2EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMRS2EditField.Editable = 'off';
            app.CMRS2EditField.Visible = 'off';
            app.CMRS2EditField.Value = '0';
            app.CMGS2EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMGS2EditFieldLabel.Visible = 'off';
            app.CMGS2EditFieldLabel.Text = '(CM) GS2';
            app.CMGS2EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMGS2EditField.Editable = 'off';
            app.CMGS2EditField.Visible = 'off';
            app.CMGS2EditField.Value = '0';
            app.CMBS2EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMBS2EditFieldLabel.Visible = 'off';
            app.CMBS2EditFieldLabel.Text = '(CM) BS2';
            app.CMBS2EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMBS2EditField.Editable = 'off';
            app.CMBS2EditField.Visible = 'off';
            app.CMBS2EditField.Value = '0';
            app.CMVS2EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMVS2EditFieldLabel.Visible = 'off';
            app.CMVS2EditFieldLabel.Text = '(CM) VS2';
            app.CMVS2EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMVS2EditField.Editable = 'off';
            app.CMVS2EditField.Visible = 'off';
            app.CMVS2EditField.Value = '0';
            app.CMRS3EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMRS3EditFieldLabel.Visible = 'off';
            app.CMRS3EditFieldLabel.Text = ' (CM) RS3';
            app.CMRS3EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMRS3EditField.Editable = 'off';
            app.CMRS3EditField.Visible = 'off';
            app.CMRS3EditField.Value = '0';
            app.CMGS3EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMGS3EditFieldLabel.Visible = 'off';
            app.CMGS3EditFieldLabel.Text = '(CM) GS3';
            app.CMGS3EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMGS3EditField.Editable = 'off';
            app.CMGS3EditField.Visible = 'off';
            app.CMGS3EditField.Value = '0';
            app.CMBS3EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMBS3EditFieldLabel.Visible = 'off';
            app.CMBS3EditFieldLabel.Text = '(CM) BS3';
            app.CMBS3EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMBS3EditField.Editable = 'off';
            app.CMBS3EditField.Visible = 'off';
            app.CMBS3EditField.Value = '0';
            app.CMVS3EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.CMVS3EditFieldLabel.Visible = 'off';
            app.CMVS3EditFieldLabel.Text = '(CM) VS3';
            app.CMVS3EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.CMVS3EditField.Editable = 'off';
            app.CMVS3EditField.Visible = 'off';
            app.CMVS3EditField.Value = '0';
            app.FileName2EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.FileName2EditFieldLabel.Enable = 'off';
            app.FileName2EditFieldLabel.Visible = 'off';
            app.FileName2EditFieldLabel.Text = 'File Name 2';
            app.FileName2EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.FileName2EditField.Editable = 'off';
            app.FileName2EditField.Visible = 'off';
            app.FileName2EditField.Value = 'NULL';
            app.FileName3EditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.FileName3EditFieldLabel.Visible = 'off';
            app.FileName3EditFieldLabel.Text = 'File Name 3';
            app.FileName3EditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.FileName3EditField.Editable = 'off';
            app.FileName3EditField.Visible = 'off';
            app.FileName3EditField.Value = 'NULL';
            app.Label_10 = uilabel(app.SimVLCUIFigure);
            app.Label_10.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.CalibrationCurveLabel = uilabel(app.SimVLCUIFigure);
            app.CalibrationCurveLabel.Visible = 'off';
            app.CalibrationCurveLabel.Text = 'Calibration Curve';
            app.Label_11 = uilabel(app.SimVLCUIFigure);
            app.Label_11.Visible = 'off';
            app.Label_11.Text = '&';
            app.CCGraphsCheckBox = uicheckbox(app.SimVLCUIFigure);
            app.CCGraphsCheckBox.Visible = 'off';
            app.CCGraphsCheckBox.Text = 'CC Graphs';
            app.CloseGraphsButton = uibutton(app.SimVLCUIFigure, 'push');
            app.CloseGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseGraphsButtonPushed, true);
            app.CloseGraphsButton.Text = 'Close Graphs';
            app.SimulationCheckBox = uicheckbox(app.SimVLCUIFigure);
            app.SimulationCheckBox.Visible = 'off';
            app.SimulationCheckBox.Text = 'Simulation';
            app.P1CheckBox = uicheckbox(app.SimVLCUIFigure);
            app.P1CheckBox.Visible = 'off';
            app.P1CheckBox.Text = 'P1';
            app.S2CheckBox = uicheckbox(app.SimVLCUIFigure);
            app.S2CheckBox.Visible = 'off';
            app.S2CheckBox.Text = 'S2';
            app.CMS1CheckBox = uicheckbox(app.SimVLCUIFigure);
            app.CMS1CheckBox.Visible = 'off';
            app.CMS1CheckBox.Text = '(CM) S1';
            app.CMS2CheckBox = uicheckbox(app.SimVLCUIFigure);
            app.CMS2CheckBox.Visible = 'off';
            app.CMS2CheckBox.Text = '(CM) S2';
            app.S3CheckBox = uicheckbox(app.SimVLCUIFigure);
            app.S3CheckBox.Visible = 'off';
            app.S3CheckBox.Text = 'S3';
            app.CMS3CheckBox = uicheckbox(app.SimVLCUIFigure);
            app.CMS3CheckBox.Visible = 'off';
            app.CMS3CheckBox.Text = '(CM) S3';
            app.ClearButton = uibutton(app.SimVLCUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Panel = uipanel(app.SimVLCUIFigure);
            app.ModulationDropDownLabel = uilabel(app.Panel);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.Panel);
            app.ModulationDropDown.Items = {'OOK', 'Manchester'};
            app.ModulationDropDown.Value = 'Manchester';
            app.OpticalInterferenceDropDownLabel = uilabel(app.Panel);
            app.OpticalInterferenceDropDownLabel.Text = 'Optical Interference';
            app.OpticalInterferenceDropDown = uidropdown(app.Panel);
            app.OpticalInterferenceDropDown.Items = {'None', 'Halogen Light', 'Fluorescent Light', 'LED Light'};
            app.OpticalInterferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @OpticalInterferenceDropDownValueChanged, true);
            app.OpticalInterferenceDropDown.Value = 'None';
            app.ParityCheckBox = uicheckbox(app.Panel);
            app.ParityCheckBox.Text = 'Parity';
            app.ParityCheckBox.Value = true;
            app.STARTButton = uibutton(app.SimVLCUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.RunningLampLabel = uilabel(app.SimVLCUIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.SimVLCUIFigure);
            app.EditField_1 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_1.Limits = [0 Inf];
            app.EditField_1.Editable = 'off';
            app.EditField_2 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.Editable = 'off';
            app.RefreshLengthButton = uibutton(app.SimVLCUIFigure, 'push');
            app.RefreshLengthButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshLengthButtonPushed, true);
            app.RefreshLengthButton.Text = 'Refresh Length';
            app.EditField_5 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.Editable = 'off';
            app.EditField_5.Visible = 'off';
            app.EditField_6 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.Editable = 'off';
            app.EditField_6.Visible = 'off';
            app.EditField_7 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_7.Limits = [0 Inf];
            app.EditField_7.Editable = 'off';
            app.EditField_7.Visible = 'off';
            app.EditField_8 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_8.Limits = [0 Inf];
            app.EditField_8.Editable = 'off';
            app.EditField_8.Visible = 'off';
            app.EditField_9 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_9.Limits = [0 Inf];
            app.EditField_9.Editable = 'off';
            app.EditField_9.Visible = 'off';
            app.EditField_10 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_10.Limits = [0 Inf];
            app.EditField_10.Editable = 'off';
            app.EditField_10.Visible = 'off';
            app.EditField_11 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_11.Limits = [0 Inf];
            app.EditField_11.Editable = 'off';
            app.EditField_11.Visible = 'off';
            app.EditField_12 = uieditfield(app.SimVLCUIFigure, 'numeric');
            app.EditField_12.Limits = [0 Inf];
            app.EditField_12.Editable = 'off';
            app.EditField_12.Visible = 'off';
            app.LengthLabel = uilabel(app.SimVLCUIFigure);
            app.LengthLabel.Text = 'Length:';
            app.Panel_2 = uipanel(app.SimVLCUIFigure);
            app.CapacitiveBehaviourCheckBox = uicheckbox(app.Panel_2);
            app.CapacitiveBehaviourCheckBox.Text = 'Capacitive Behaviour';
            app.CapacitiveBehaviourCheckBox.Value = true;
            app.CurvingRateLabel = uilabel(app.Panel_2);
            app.CurvingRateLabel.Text = {'Curving'; 'Rate'};
            app.CurvingRateEditField = uieditfield(app.Panel_2, 'numeric');
            app.CurvingRateEditField.Limits = [0 99];
            app.CurvingRateEditField.Value = 3.4;
            app.SamplesEditFieldLabel = uilabel(app.Panel_2);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.Panel_2, 'numeric');
            app.SamplesEditField.Limits = [1 99];
            app.SamplesEditField.Value = 16;
            app.ScenariosPanel = uipanel(app.SimVLCUIFigure);
            app.ScenariosPanel.Title = 'Scenarios';
            app.Scenario1Button = uibutton(app.ScenariosPanel, 'push');
            app.Scenario1Button.ButtonPushedFcn = createCallbackFcn(app, @Scenario1ButtonPushed, true);
            app.Scenario1Button.Text = 'Scenario 1';
            app.Scenario2t0Button = uibutton(app.ScenariosPanel, 'push');
            app.Scenario2t0Button.ButtonPushedFcn = createCallbackFcn(app, @Scenario2t0ButtonPushed, true);
            app.Scenario2t0Button.Text = 'Scenario 2 (t0)';
            app.Scenario2t1Button = uibutton(app.ScenariosPanel, 'push');
            app.Scenario2t1Button.ButtonPushedFcn = createCallbackFcn(app, @Scenario2t1ButtonPushed, true);
            app.Scenario2t1Button.Text = 'Scenario 2 (t1)';
            app.Scenario3UserAButton = uibutton(app.ScenariosPanel, 'push');
            app.Scenario3UserAButton.ButtonPushedFcn = createCallbackFcn(app, @Scenario3UserAButtonPushed, true);
            app.Scenario3UserAButton.Text = 'Scenario 3 (User A)';
            app.Scenario3UserBButton = uibutton(app.ScenariosPanel, 'push');
            app.Scenario3UserBButton.ButtonPushedFcn = createCallbackFcn(app, @Scenario3UserBButtonPushed, true);
            app.Scenario3UserBButton.Text = 'Scenario 3 (User B)';
            app.ShowBERatOutputCheckBox = uicheckbox(app.SimVLCUIFigure);
            app.ShowBERatOutputCheckBox.Text = 'Show BER at Output';
            app.ShowBERatOutputCheckBox.Value = true;
            app.Label_12 = uilabel(app.SimVLCUIFigure);
            app.Label_12.Text = '';
            app.Switch = uiswitch(app.SimVLCUIFigure, 'slider');
            app.Switch.Items = {'Simulation', 'Lab Measurement'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.Switch.Value = 'Simulation';
            app.Lamp = uilamp(app.SimVLCUIFigure);
            app.Lamp_2 = uilamp(app.SimVLCUIFigure);
            app.ChooseFileButton = uibutton(app.SimVLCUIFigure, 'state');
            app.ChooseFileButton.ValueChangedFcn = createCallbackFcn(app, @ChooseFileButtonValueChanged, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.SelectedFileEditFieldLabel = uilabel(app.SimVLCUIFigure);
            app.SelectedFileEditFieldLabel.Text = 'Selected File:';
            app.SelectedFileEditField = uieditfield(app.SimVLCUIFigure, 'text');
            app.SelectedFileEditField.Value = 'Click on ''Choose File'' to import excel file';
            % Show the figure after all components are created
            app.SimVLCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimVLC

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SimVLCUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SimVLCUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimVLCUIFigure)
        end
    end
end